classdef AppOrbit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InsertvaluetostartLabel       matlab.ui.control.Label
        ByMohamedHarhashLabel         matlab.ui.control.Label
        OrbitSimulatorLabel           matlab.ui.control.Label
        TrueAnomalySlider             matlab.ui.control.Slider
        TrueAnomalySliderLabel        matlab.ui.control.Label
        ArgumentofPerigeeSlider       matlab.ui.control.Slider
        ArgumentofPerigeeSliderLabel  matlab.ui.control.Label
        RAANSlider                    matlab.ui.control.Slider
        RAANSliderLabel               matlab.ui.control.Label
        InclinationSlider             matlab.ui.control.Slider
        InclinationSliderLabel        matlab.ui.control.Label
        EccentricitySlider            matlab.ui.control.Slider
        EccentricitySliderLabel       matlab.ui.control.Label
        SemimajoraxisEditField        matlab.ui.control.NumericEditField
        SemimajoraxisEditFieldLabel   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ArgumentofPerigeeSlider, 
        % ...and 5 other components
        function SemimajoraxisEditFieldValueChanged(app, event)
           
            % Plot Earth
hold(app.UIAxes, "off");
[Xe, Ye, Ze] = sphere(70);
earth_radius = 6378;
surf(app.UIAxes, Xe * earth_radius, Ye * earth_radius, -Ze * earth_radius, 'FaceColor', 'texturemap', 'CData', imread("EARTH.jpg"), 'EdgeColor', 'none');
hold(app.UIAxes, "on");
axis(app.UIAxes, "equal");
%grid on;

% Define Orbital Elements
a = app.SemimajoraxisEditField.Value;      % Semi-major axis
e = app.EccentricitySlider.Value;          % Eccentricity
incl = -app.InclinationSlider.Value;       % Inclination
RA = app.RAANSlider.Value;         % Right Ascension of the Ascending Node (RAAN)
w = app.ArgumentofPerigeeSlider.Value;          % Argument of Perigee
TA = app.TrueAnomalySlider.Value;         % True Anomaly

% Convert Orbital Elements to Position and Velocity Vectors
[R0, V0] = COE2RV(a, e, TA, RA, incl, w);
X0 = [R0; V0];

% Calculate Orbital Period
mu = 398600;
T_orbit = 2 * pi * sqrt(a^3 / mu);

% Integrate the Equations of Motion
options = odeset('RelTol', 1e-8, 'AbsTol', 1e-10);
t_span = 0:10:T_orbit * 2;
[t, state] = ode45(@drfforbit, t_span, X0, options);

% Extract Position Components
X = state(:, 1);
Y = state(:, 2);
Z = state(:, 3);

% Plot the Orbital Trajectory
plot3(app.UIAxes, X, Y, Z, '.r');
axis(app.UIAxes, "off");
%surf(500*Xe+X(end),500*Ye+Y(end),500*Ze+Z(end));
q = quaternion([0 0 0], "eulerd" , "ZYX", "frame");
poseplot(app.UIAxes, q, [X(end) Y(end) Z(end)], MeshFileName='Satellite1.STL', ScaleFactor=5, PatchFaceColor='b');

% Function to Calculate Derivatives of Position and Velocity
function ydot = drfforbit(t, y)
    r = y(1:3);      % Position vector [km]
    v = y(4:6);      % Velocity vector [km/s]
    mu = 398600;     % Earth's gravitational parameter [km^3/s^2]
    R = norm(r);     % Distance from Earth's center [km]

    % Acceleration
    a = -mu * r / R^3;
    
    % Derivative of state vector
    ydot = [v; a];
end

% Function to Convert Orbital Elements to Position and Velocity Vectors
function [R0, V0] = COE2RV(a, e, TA, RA, incl, w)
    % Convert degrees to radians for trigonometric functions
    RA_rad = deg2rad(RA);
    incl_rad = deg2rad(incl);
    w_rad = deg2rad(w);
    TA_rad = deg2rad(TA);
    
    % Gravitational parameter
    mu = 398600; % [km^3/s^2]
    
    % Semi-latus rectum
    p = a * (1 - e^2);
    
    % Specific angular momentum
    h = sqrt(mu * p);
    
    % Position in Perifocal Coordinate System
    rp = (p / (1 + e * cos(TA_rad)) ) * [cos(TA_rad); sin(TA_rad); 0];
    
    % Velocity in Perifocal Coordinate System
    vp = (mu / h) * [-sin(TA_rad); e + cos(TA_rad); 0];
    
    % Rotation Matrices
    % Rotation about Z-axis by RAAN
    R3_RAAN = [cos(RA_rad), -sin(RA_rad), 0;
               sin(RA_rad),  cos(RA_rad), 0;
               0,            0,           1];
           
    % Rotation about X-axis by Inclination
    R1_incl = [1, 0,             0;
              0, cos(incl_rad), -sin(incl_rad);
              0, sin(incl_rad),  cos(incl_rad)];
          
    % Rotation about Z-axis by Argument of Periapsis
    R3_arg = [cos(w_rad), -sin(w_rad), 0;
              sin(w_rad),  cos(w_rad), 0;
              0,           0,          1];
          
    % Combined Rotation Matrix: R = R3(RAAN) * R1(incl) * R3(argument of periapsis)
    Q = R3_RAAN * R1_incl * R3_arg;
    
    % Transform to ECI Frame
    R0 = Q * rp;
    V0 = Q * vp;
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SemimajoraxisEditFieldLabel = uilabel(app.UIFigure);
            app.SemimajoraxisEditFieldLabel.Text = 'Semi-major axis';
            app.SemimajoraxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.SemimajoraxisEditField.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxisEditFieldValueChanged, true);
            app.EccentricitySliderLabel = uilabel(app.UIFigure);
            app.EccentricitySliderLabel.Text = 'Eccentricity';
            app.EccentricitySlider = uislider(app.UIFigure);
            app.EccentricitySlider.Limits = [0 1];
            app.EccentricitySlider.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxisEditFieldValueChanged, true);
            app.InclinationSliderLabel = uilabel(app.UIFigure);
            app.InclinationSliderLabel.Text = 'Inclination';
            app.InclinationSlider = uislider(app.UIFigure);
            app.InclinationSlider.Limits = [0 180];
            app.InclinationSlider.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxisEditFieldValueChanged, true);
            app.RAANSliderLabel = uilabel(app.UIFigure);
            app.RAANSliderLabel.Text = 'RAAN';
            app.RAANSlider = uislider(app.UIFigure);
            app.RAANSlider.Limits = [0 360];
            app.RAANSlider.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxisEditFieldValueChanged, true);
            app.ArgumentofPerigeeSliderLabel = uilabel(app.UIFigure);
            app.ArgumentofPerigeeSliderLabel.Text = 'Argument of Perigee';
            app.ArgumentofPerigeeSlider = uislider(app.UIFigure);
            app.ArgumentofPerigeeSlider.Limits = [0 360];
            app.ArgumentofPerigeeSlider.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxisEditFieldValueChanged, true);
            app.TrueAnomalySliderLabel = uilabel(app.UIFigure);
            app.TrueAnomalySliderLabel.Text = 'True Anomaly';
            app.TrueAnomalySlider = uislider(app.UIFigure);
            app.TrueAnomalySlider.Limits = [0 360];
            app.TrueAnomalySlider.ValueChangedFcn = createCallbackFcn(app, @SemimajoraxisEditFieldValueChanged, true);
            app.OrbitSimulatorLabel = uilabel(app.UIFigure);
            app.OrbitSimulatorLabel.Text = 'Orbit Simulator';
            app.ByMohamedHarhashLabel = uilabel(app.UIFigure);
            app.ByMohamedHarhashLabel.Text = 'By: Mohamed Harhash';
            app.InsertvaluetostartLabel = uilabel(app.UIFigure);
            app.InsertvaluetostartLabel.Text = '"Insert value to start"';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppOrbit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end